classdef SmartCommute < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SmartCommutePanel               matlab.ui.container.Panel
        Instructions                    matlab.ui.control.TextArea
        EnterzipcodehereEditFieldLabel  matlab.ui.control.Label
        ZipcodeField                    matlab.ui.control.NumericEditField
        SelecttimeintervalhereDropDownLabel  matlab.ui.control.Label
        TimeDropDown                    matlab.ui.control.DropDown
        GoGraph                         matlab.ui.control.CheckBox
        EnterButton                     matlab.ui.control.Button
        OutputMessage                   matlab.ui.control.TextArea
        maketxt                         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
    userzip;
    usertime;
    ZipcodeData;
    maxpop;
    maxtime;
    end


    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.userzip = app.ZipcodeField.Value;
            app.usertime = app.TimeDropDown.Items;
            app.TimeDropDown.ItemsData = 1:14;
            timeindex = app.TimeDropDown.Value;
            %Spreadsheet extraction
            AllData = extractSpreadsheetData();
            %Specific data for specified zipcode
            app.ZipcodeData = extractZip(AllData, app.userzip);
            NumAtTime = app.ZipcodeData(timeindex);
            %Prints the number of people in the designated zipcode
            app.OutputMessage.Value = sprintf('There are %d people leaving between %s in your zipcode.\n',NumAtTime,app.usertime{app.TimeDropDown.Value});
            %Graphs all data for zipcode if checkbox is checked
            if app.GoGraph.Value == 1 
                GraphZipData(app.TimeDropDown.Items,app.userzip,app.ZipcodeData)
            end
            %Creates the file ZipcodeInfo.txt if the box is checked
            if app.maketxt.Value == 1
               maketxtfxn(app.TimeDropDown.Items,app.ZipcodeData,app.userzip); 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SmartCommutePanel = uipanel(app.UIFigure);
            app.SmartCommutePanel.Title = 'SmartCommute';
            app.Instructions = uitextarea(app.SmartCommutePanel);
            app.Instructions.Editable = 'off';
            app.Instructions.Value = {'Enter your zipcode and time interval below to find out how many people leave for work at that time in your area!'};
            app.EnterzipcodehereEditFieldLabel = uilabel(app.SmartCommutePanel);
            app.EnterzipcodehereEditFieldLabel.Text = 'Enter zipcode here:';
            app.ZipcodeField = uieditfield(app.SmartCommutePanel, 'numeric');
            app.SelecttimeintervalhereDropDownLabel = uilabel(app.SmartCommutePanel);
            app.SelecttimeintervalhereDropDownLabel.Text = 'Select time interval here:';
            app.TimeDropDown = uidropdown(app.SmartCommutePanel);
            app.TimeDropDown.Items = {'12:00 a.m. to 4:59 a.m.', '5:00 a.m. to 5:29 a.m.', '5:30 a.m. to 5:59 a.m.', '6:00 a.m. to 6:29 a.m.', '6:30 a.m. to 6:59 a.m.', '7:00 a.m. to 7:29 a.m.', '7:30 a.m. to 7:59 a.m.', '8:00 a.m. to 8:29 a.m.', '8:30 a.m. to 8:59 a.m.', '9:00 a.m. to 9:59 a.m.', '10:00 a.m. to 10:59 a.m.', '11:00 a.m. to 11:59 a.m.', '12:00 p.m. to 3:59 p.m.', '4:00 p.m. to 11:59 p.m.'};
            app.TimeDropDown.Value = '12:00 a.m. to 4:59 a.m.';
            app.GoGraph = uicheckbox(app.SmartCommutePanel);
            app.GoGraph.Text = 'Display all of the commuter data in a graph.';
            app.EnterButton = uibutton(app.SmartCommutePanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.OutputMessage = uitextarea(app.SmartCommutePanel);
            app.OutputMessage.Editable = 'off';
            app.OutputMessage.Value = {'Your results will be displayed here.'};
            app.maketxt = uicheckbox(app.SmartCommutePanel);
            app.maketxt.Text = 'Download the zipcode data to a ZipcodeInfo.txt file.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SmartCommute

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end